classdef WiBlinkAuth < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Panel                    matlab.ui.container.Panel
        StartButton              matlab.ui.control.Button
        DeleteButton             matlab.ui.control.Button
        Label                    matlab.ui.control.Label
        FolderEditFieldLabel     matlab.ui.control.Label
        FolderEditField          matlab.ui.control.EditField
        CountButton              matlab.ui.control.Button
        Label_2                  matlab.ui.control.Label
        BeginButton              matlab.ui.control.Button
        DurationsEditFieldLabel  matlab.ui.control.Label
        DurationsEditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        MemoryTimer % Timer object
    end
    
    methods (Access = public)
        
        function results = ScanFileNum(app)
            global FNum;
            FileDir = app.FolderEditField.Value;
            FileLike = '*.mat';
            if ~exist(FileDir, 'dir')
                mkdir(FileDir);
            end
            Dirs = dir(fullfile(FileDir, FileLike));
            FNum = length(Dirs);
            app.Label.Text = sprintf('%02d', FNum);
            results = FNum;
            
        end
    end
    
    methods (Access = public)
        
        function results = Groundtruth(app, Time)
            global GroundTruth;
            if ~isa(Time, 'double')
                results = false;
                warning('Orm: Input Time is not a double!');
                return;
            end
            switch Time
                case -1
                    GroundTruth = []; % initialize
                    app.Label_2.Text = sprintf('%03d', length(GroundTruth));
                case 0
                     % just return the array
                otherwise 
                    GroundTruth = cat(1, GroundTruth, Time);
                    app.Label_2.Text = sprintf('%03d', length(GroundTruth));

            end
            results = GroundTruth;
        end
    end
    
    methods (Access = private)
        
        function Re = StCallback(app)
            global Log LogStClock;
            State = app.StartButton.Text;
            if strcmp(State, 'Start')
                app.BeginButton.Enable = 'off';
                app.DeleteButton.Enable = 'off';
                app.CountButton.Enable = 'on';
                app.Label_2.Enable = 'on';
                app.StartButton.Text = 'Stop';
                Log = true;
                LogStClock = clock;
                Groundtruth(app, -1);
                start(app.MemoryTimer)
                disp('Start logging');
            else
                app.BeginButton.Enable = 'on';
                app.Label_2.Enable = 'off';
                app.StartButton.Text = 'Start';
                Log = false;
                disp('End logging');
            end
        end
        
        function results = MyTimerFcn(app, ~, ~)
            global Log;
            if Log
                StCallback(app);
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function BackgroundFigShow(app)
            global Running AxesHandle Log;
            Re = ScanFileNum(app);
            AxesHandle = cell(4,1);
            Running = false;
            Log = false;
            app.MemoryTimer = timer(...
                'ExecutionMode', 'singleShot', ...    % Run timer repeatedly
                'StartDelay', app.DurationsEditField.Value, ...
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.MyTimerFcn);    % Specify callback function
        end

        % Key press function: UIFigure
        function BackgroundKey(app, event)
            global LogStClock Log;
            key = event.Key;
            disp(key);
            switch key
                case 'space'
                    if ~isempty(LogStClock) && Log
                        Groundtruth(app,etime(clock, LogStClock));
                    end
                case 'c'
                    Groundtruth(app,-1);
                case 's'
                    if strcmp(app.StartButton.Enable, 'on')
                        StCallback(app);
                    end
            end
        end

        % Value changed function: FolderEditField
        function FolderChangedCallback(app, event)
            ScanFileNum(app);
        end

        % Button pushed function: BeginButton
        function BeginCallback(app, event)
            global Running AxesHandle LastAccessBegin;
%             if ~isempty(LastAccessBegin) && etime(clock, LastAccessBegin) < 2
%                 LastAccessBegin = clock;
%                 return;
%             end
%             LastAccessBegin = clock;
            app.BeginButton.Enable = 'off';
            if (~strcmp(app.BeginButton.Text, 'Begin'))
                Running = false;
                app.BeginButton.Text = 'Begin';
                app.BeginButton.Enable = 'on';
                app.StartButton.Text = 'Start';
                app.StartButton.Enable = 'off';
            else
                app.StartButton.Enable = 'on';
                for i = 1 : 4
                    if ishandle(AxesHandle{i})
                        delete(AxesHandle{i});
                        AxesHandle{i} = [];
                    end
                end
                Running = true;
                app.BeginButton.Text = 'End';
                Groundtruth(app, -1);
                app.BeginButton.Enable = 'on';
                try
                    Re = orm_data_processing_online8(app);
                catch ME
                    errordlg(sprintf('ERROR %s IN %s AT LINE %d',...
                        ME.message, ME.stack.name, ME.stack.line));
                    Running = false;
                    app.BeginButton.Text = 'Begin';
                    app.StartButton.Text = 'Start';
                    app.StartButton.Enable = 'off';
                    disp(getReport(ME))
                    return;
%                     rethrow(ME);
                end
            end
            
        end

        % Button pushed function: StartButton
        function StartCallback(app, event)
            StCallback(app);
        end

        % Button pushed function: DeleteButton
        function DeleteCallback(app, event)
            global FNum;
            FileDir = app.FolderEditField.Value;
            FileName = fullfile(FileDir, sprintf('%02d.mat', FNum));
            if exist(FileName, 'file')
                delete(FileName);
                app.Label.Text = sprintf('%02d', max(FNum - 1, 0));
                FNum = max(FNum - 1, 0);
            end
        end

        % Button pushed function: CountButton
        function CountCallback(app, event)
            global LogStClock Log;
            if ~isempty(LogStClock) && Log
                Groundtruth(app,etime(clock, LogStClock));
            end
        end

        % Callback function
        function TestCallback(app, event)
%             ax = subplot(2,2,1,'Parent',app.Panel);
%             plot(ax, 1:10,1:10);
%             set(ax, 'FontSize',14);
%             axis(ax,'tight');
%             hold(ax, 'on')
%             plot(ax, 1:10,ones(10,1));
%             hold(ax, 'off');
%             xlabel(ax,'time/s');
            Re = Tmp0(app);
            fprintf('%03d\n',length(Re));
%             delete(ax);
%             a = Groundtruth(app,2);
%             disp(a)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.MemoryTimer.Running == true
                stop(app.MemoryTimer);
            end
            delete(app.MemoryTimer);
            delete(app);
        end

        % Value changed function: DurationsEditField
        function ChangeTimerCallback(app, event)
            value = app.DurationsEditField.Value;
            Dur = app.DurationsEditField.Value;
            if Dur > 0  && ~isinf(Dur)
                delete(app.MemoryTimer);
                app.MemoryTimer = timer(...
                    'ExecutionMode', 'singleShot', ...    % Run timer repeatedly
                    'StartDelay', Dur, ...
                    'BusyMode', 'queue',...              % Queue timer callbacks when busy
                    'TimerFcn', @app.MyTimerFcn);    % Specify callback function
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @BackgroundKey, true);
            app.Panel = uipanel(app.UIFigure);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartCallback, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteCallback, true);
            app.DeleteButton.Text = 'Delete';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '00';
            app.FolderEditFieldLabel = uilabel(app.UIFigure);
            app.FolderEditFieldLabel.Text = 'Folder';
            app.FolderEditField = uieditfield(app.UIFigure, 'text');
            app.FolderEditField.ValueChangedFcn = createCallbackFcn(app, @FolderChangedCallback, true);
            app.FolderEditField.Value = 'WiBlink/Runmin_Ou';
            app.CountButton = uibutton(app.UIFigure, 'push');
            app.CountButton.ButtonPushedFcn = createCallbackFcn(app, @CountCallback, true);
            app.CountButton.Enable = 'off';
            app.CountButton.Text = 'Count';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Enable = 'off';
            app.Label_2.Text = '000';
            app.BeginButton = uibutton(app.UIFigure, 'push');
            app.BeginButton.ButtonPushedFcn = createCallbackFcn(app, @BeginCallback, true);
            app.BeginButton.Text = 'Begin';
            app.DurationsEditFieldLabel = uilabel(app.UIFigure);
            app.DurationsEditFieldLabel.Text = 'Duration/s:';
            app.DurationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DurationsEditField.Limits = [0 Inf];
            app.DurationsEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeTimerCallback, true);
            app.DurationsEditField.Value = 130;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WiBlinkAuth

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @BackgroundFigShow)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end